classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DTMFGeneratorLabel  matlab.ui.control.Label
        SoundAllButton      matlab.ui.control.Button
        resetButton         matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        ButtonGroup         matlab.ui.container.ButtonGroup
        Button_12           matlab.ui.control.Button
        Button_10           matlab.ui.control.Button
        Button_11           matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        global v;
        v= '1';
      app.EditField.Value=strcat(app.EditField.Value,v);
            delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(1,delta,nd,np));
        
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
       global v;
       v= '2';
      app.EditField.Value=strcat(app.EditField.Value,v);
       delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(2,delta,nd,np));


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
          global v;
        v= '3';
        app.EditField.Value=strcat(app.EditField.Value,v);
              delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(3,delta,nd,np));

      
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
               global v;
       v= '4';
      app.EditField.Value=strcat(app.EditField.Value,v);
            
              delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(4,delta,nd,np));
        
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             global v;
       v= '5';
      app.EditField.Value=strcat(app.EditField.Value,v);
             delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(5,delta,nd,np));
   
      
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
              global v;
       v= '6';
    
      app.EditField.Value=strcat(app.EditField.Value,v);
            delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(6,delta,nd,np));


        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
              global v;
       v= '7';
      app.EditField.Value=strcat(app.EditField.Value,v);
            
              delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(7,delta,nd,np));


        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
              global v;
       v= '8';
      app.EditField.Value=strcat(app.EditField.Value,v);
              delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(8,delta,nd,np));
        
        
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
              global v;
       v= '9';
      app.EditField.Value=strcat(app.EditField.Value,v);
               delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(9,delta,nd,np));
   
     
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
              global v;
       v= '10';
      app.EditField.Value=strcat(app.EditField.Value,v);
              delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(10,delta,nd,np));
     
       
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
               global v;
       v= '0';
      app.EditField.Value=strcat(app.EditField.Value,v);
            
             delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(0,delta,nd,np));
      
     
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
              global v;
       v= '11';
           delta = 1/8000;
nd = 1600;
np = 640;
       
        
       plot(app.UIAxes,ss_dtmf(11,delta,nd,np));
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.EditField.Value='';
            cla(app.UIAxes,'reset');
            cla(app.UIAxes3,'reset');
           
        end

        % Button pushed function: SoundAllButton
        function SoundAllButtonPushed(app, event)
            global v;
            x=[];
             delta = 1/8000;
nd = 1600;
np = 640;
            y=app.EditField.Value;
            y=strcat(app.EditField.Value,v);
            x = cat(2,x,ss_dtmf(y,delta,nd,np));
            plot(app.UIAxes3,x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Tone')
            xlabel(app.UIAxes, 'Time (Second)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'All Tones')
            xlabel(app.UIAxes3, 'Time (Second)')
            ylabel(app.UIAxes3, 'Amplitude')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Button = uibutton(app.ButtonGroup, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.ButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.ButtonGroup, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.ButtonGroup, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.ButtonGroup, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.ButtonGroup, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.ButtonGroup, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.ButtonGroup, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.ButtonGroup, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_11 = uibutton(app.ButtonGroup, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '*';
            app.Button_10 = uibutton(app.ButtonGroup, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_12 = uibutton(app.ButtonGroup, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.SoundAllButton = uibutton(app.UIFigure, 'push');
            app.SoundAllButton.ButtonPushedFcn = createCallbackFcn(app, @SoundAllButtonPushed, true);
            app.SoundAllButton.Text = 'Sound All';
            app.DTMFGeneratorLabel = uilabel(app.UIFigure);
            app.DTMFGeneratorLabel.Text = 'DTMF Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end